classdef Giao_Dien_Tram_Du_Bao < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        NameApp                matlab.ui.control.Label
        DoAm_Text              matlab.ui.control.TextArea
        mTextAreaLabel         matlab.ui.control.Label
        NhitLabel              matlab.ui.control.Label
        NhietDo_Text           matlab.ui.control.TextArea
        Version                matlab.ui.control.Label
        BaudRate_Sec           matlab.ui.control.DropDown
        BaudRateDropDownLabel  matlab.ui.control.Label
        COM_Sec                matlab.ui.control.DropDown
        CngDropDownLabel       matlab.ui.control.Label
        End_Button             matlab.ui.control.Button
        DieuKhien_Name         matlab.ui.control.Label
        Refresh_Button         matlab.ui.control.Button
        Status_Label           matlab.ui.control.Label
        Status_Name            matlab.ui.control.Label
        Disconnect_Button      matlab.ui.control.Button
        Start_Button           matlab.ui.control.Button
        TuyChon_Name           matlab.ui.control.Label
        Connect_Button         matlab.ui.control.Button
        DoAmAxe                matlab.ui.control.UIAxes
        NhietDoAxe             matlab.ui.control.UIAxes
    end


    % Public properties that correspond to the Simulink model
    properties (Access = public, Transient)
        Simulation simulink.Simulation
    end

    
    properties (Access = private)
        SerialDevice % Biến lưu cổng COM
        ThoiGianDoc % Thời gian đọc dữ liệu
        TrangThai % Trạng thái có đang đọc Arduino hay không

        TimeData    double % Mảng lưu thời gian
        TempData    double % Mảng lưu nhiệt độ
        HumData     double % Mảng lưu độ ẩm
        BatDauTime  datetime % Mốc thời gian bắt đầu
    end
    
    methods (Access = private)
        
        function DocvaVe(app)
            % Nếu không còn đọc hoặc mất kết nối -> Dừng
            if ~app.TrangThai || isempty(app.SerialDevice) || ~isvalid(app.SerialDevice)
                End_ButtonPushed(app, []);
                return;
            end

            try
                % Kiểm tra xem có dữ liệu trong bộ nhớ đệm hay không
                if app.SerialDevice.NumBytesAvailable > 0
                    % Đọc dữ liệu: "T:...,H:..."
                    DL = readline(app.SerialDevice);
                    DL = strtrim(string(DL));

                    % Phân tích chuỗi
                    giatri = sscanf(char(DL), 'T:%f,H:%f');

                    % Kiểm tra xem có 2 dữ liệu hay không?
                    if numel(giatri) == 2
                        ND_Hientai = giatri(1);
                        DA_Hientai = giatri(2);

                        TG_Hientai = seconds(datetime("now") - app.BatDauTime);

                        % Cập nhật UI
                        app.NhietDo_Text.Value = num2str(ND_Hientai, '%.1f');
                        app.DoAm_Text.Value = num2str(DA_Hientai, '%.1f');

                        % Thêm dữ liệu vào mảng
                        app.TimeData(end+1) = TG_Hientai;
                        app.TempData(end+1) = ND_Hientai;
                        app.HumData(end+1) = DA_Hientai;

                        % Đặt giới hạn 100 điểm dữ liệu
                        maxpoint = 100;
                        if numel(app.TimeData) > maxpoint
                            app.TimeData = app.TimeData(end-maxpoint+1:end);
                            app.TempData = app.TempData(end-maxpoint+1:end);
                            app.HumData = app.HumData(end-maxpoint+1:end);
                        end

                        % Vẽ đồ thị
                        plot(app.NhietDoAxe, app.TimeData, app.TempData, 'r-','LineWidth',1.5);
                        plot(app.DoAmAxe,app.TimeData, app.HumData, 'b-', 'LineWidth', 1.5);

                        % Điều chỉnh trục X
                        if numel(app.TimeData) > 1
                            xlim(app.NhietDoAxe,[app.TimeData(1), app.TimeData(end)]);
                            xlim(app.DoAmAxe,[app.TimeData(1), app.TimeData(end)]);
                        end
                    else
                    end
                end
            catch
                uialert(app.UIFigure, 'Lỗi đọc dữ liệu', 'Lỗi');
                app.Status_Label.Text = 'Lỗi đọc serial';
                app.Status_Label.FontColor = 'red';
                drawnow;
                
                pause(1);
                End_ButtonPushed(app, []);
            end
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % === CẬP NHẬT NÚT LOGIC ===
            % Trạng thái ban đầu
            app.Start_Button.Enable = 'off';
            app.End_Button.Enable = 'off';
            app.Disconnect_Button.Enable = 'off';

            % Gọi nút làm mới với không cần tham số event [] 
            Refresh_ButtonPushed(app,[]);

            % === CẬP NHẬT TRẠNG THÁI ===
            % Trạng thái bắt đầu
            app.Status_Label.Text = 'Chưa kết nối';
            app.Status_Label.FontColor = 'black';

            % === CẬP NHẬT ĐỒ THỊ ===
            ylabel(app.NhietDoAxe, 'Nhiệt độ (C)');
            xlabel(app.NhietDoAxe, 'Thời gian (s)');
            grid(app.NhietDoAxe, 'on');
            
            ylabel(app.DoAmAxe, 'Độ ẩm (%)');
            xlabel(app.DoAmAxe, 'Thời gian (s)');
            grid(app.DoAmAxe, 'on');

            % Cập nhật thông tin hiển thị
            app.NhietDo_Text.Enable = "off";
            app.DoAm_Text.Enable = 'off';
            app.NhietDo_Text.Value = '';
            app.DoAm_Text.Value = '';
            
        end

        % Button pushed function: Refresh_Button
        function Refresh_ButtonPushed(app, event)
            % === LOAD DANH SÁCH CỔNG ===
            COM_Port_Str = serialportlist("available"); % Hàm trả về mảng string
            COM_Port = cellstr(COM_Port_Str); % Hàm chuyển sang cell array để thêm bớt

            placeholder = 'Chọn cổng';

            % === CẬP NHẬT DANH SACH CỔNG ===
            if isempty (COM_Port)
                app.COM_Sec.Items = {'Không thấy cổng COM'};
                app.COM_Sec.Value = 'Không thấy cổng COM';
            else
                app.COM_Sec.Items = [placeholder; COM_Port(:)]; % COM_Port là vector cột (:)
                app.COM_Sec.Value = placeholder; % Đặt giá trị là placeholder nếu tìm thấy cổng
            end
        end

        % Button pushed function: Connect_Button
        function Connect_ButtonPushed(app, event)
            selected_Port = app.COM_Sec.Value;
            selected_BaudRate = str2double(app.BaudRate_Sec.Value);

            % Kiểm tra người dùng chọn cổng hay chưa
            if strcmp (selected_Port, 'Chọn cổng')
                uialert(app.UIFigure, 'Vui lòng chọn cổng COM từ danh sách.', 'Lỗi','Icon', 'none');
                return;
            end

            % Kiểm tra nếu không có cổng nào
            if strcmp (selected_Port, 'Không thấy cổng COM')
                uialert(app.UIFigure, 'Vui lòng kiểm tra lại cổng kết nối và nhấn Làm mới', 'Lỗi');
                return;
            end

            try
                % === CẬP NHẬT TRẠNG THÁI ===
                % Trạng thái 'Đang kết nối'
                app.Status_Label.Text = 'Đang kết nối...';
                app.Status_Label.FontColor = [0.85 0.33 0.1]; % Màu Cam
                drawnow; % Cập nhật ngay lập tức

                % Kết nối
                app.SerialDevice = serialport(selected_Port, selected_BaudRate);
                configureTerminator(app.SerialDevice, "LF");
                uialert(app.UIFigure, ['Kết nối tới ' selected_Port ' thành công.'], 'Thành công', 'Icon', 'success');

                % === CẬP NHẬT TRẠNG THÁI ===
                % Trạng thái 'Đã kết nối'
                app.Status_Label.Text = 'Đã kết nối';
                app.Status_Label.FontColor = [0 0.6 0.1]; % Màu xanh

                % === CẬP NHẬT NÚT LOGIC ===
                % Trạng thái "Đã kết nối'
                % Tùy chọn
                app.Connect_Button.Enable = 'off';
                app.Refresh_Button.Enable = 'off';
                app.COM_Sec.Enable = 'off';
                app.BaudRate_Sec.Enable = 'off';

                % Điều khiển
                app.Start_Button.Enable = 'on';
                app.End_Button.Enable = 'off';
                app.Disconnect_Button.Enable = 'on';
            catch
                uialert(app.UIFigure, ['Kết nối tới ' selected_Port ' thất bại'], 'Thất bại');
                app.Status_Label.Text = 'Kết nối lỗi';
                app.Status_Label.FontColor = 'red';
            end

        end

        % Button pushed function: Disconnect_Button
        function Disconnect_ButtonPushed(app, event)
            % Kiểm tra trạng thái đọc
            if app.TrangThai
                End_ButtonPushed(app,[]); % Gọi nút END
            end
            
            % Kiểm tra kết nối
            if ~isempty(app.SerialDevice) && isvalid(app.SerialDevice)
                clear app.SerialDevice;
                app.SerialDevice = [];

                uialert(app.UIFigure, 'Đã ngắt kết nối.', 'Thành công', 'Icon', 'success');
            end

            % === CẬP NHẬT TRẠNG THÁI ===
            app.Status_Label.Text = 'Đã ngắt kết nối';
            app.Status_Label.FontColor = 'black';

            % === CẬP NHẬT NÚT LOGIC ===
            % Tùy chọn
            app.Connect_Button.Enable = 'on';
            app.Refresh_Button.Enable = 'on';
            app.COM_Sec.Enable = 'on';
            app.BaudRate_Sec.Enable = 'on';

            % Điều khiển
            app.Start_Button.Enable = 'off';
            app.End_Button.Enable = 'off';
            app.Disconnect_Button.Enable = 'off';
            drawnow;

            pause(1); % Tạm dừng chương trình 1s
            
            % === CẬP NHẬT TRẠNG THÁI ===
            % Trạng thái ban đầu
            Refresh_ButtonPushed(app,[]);
            app.Status_Label.Text = 'Chưa kết nối';
            app.Status_Label.FontColor = 'black';

            % Cập nhật Hiển thị
            app.NhietDo_Text.Enable = "off";
            app.DoAm_Text.Enable = 'off';
            app.NhietDo_Text.Value = '';
            app.DoAm_Text.Value = '';
        end

        % Button pushed function: Start_Button
        function Start_ButtonPushed(app, event)
            if isempty(app.SerialDevice) || ~isvalid(app.SerialDevice)
                uialert(app.UIFigure, 'Kết nối lỗi. Vui lòng kết nối lại.', 'Lỗi');
                return;
            end

            try
                % Xóa dữ liệu cũ và đồ thị
                app.TimeData = [];
                app.TempData = [];
                app.HumData = [];
                cla(app.NhietDoAxe);
                cla(app.DoAmAxe);

                % Lấy mốc thời gian
                app.BatDauTime = datetime('now');

                % Xóa bộ đệm Serial (dữ liệu cũ)
                flush(app.SerialDevice);

                % === CẬP NHẬT TRẠNG THÁI ===
                app.TrangThai = true; % Đặt trạng thái đọc
                app.Status_Label.Text = 'Đang đọc dữ liệu...';
                app.Status_Label.FontColor = [0 0.4 0.7]; % Màu xanh

                app.NhietDo_Text.Enable = "on";
                app.DoAm_Text.Enable = 'on';

                % === CẬP NHẬT NÚT LOGIC ===
                app.Start_Button.Enable = 'off';
                app.End_Button.Enable = 'on';
                app.Disconnect_Button.Enable = 'off';

                % Kiểm tra dữ liệu thời gian
                if ~isempty(app.ThoiGianDoc)
                    stop(app.ThoiGianDoc);
                    delete(app.ThoiGianDoc);
                end

                % Tạo thời gian mới
                app.ThoiGianDoc = timer('TimerFcn', @(~,~) app.DocvaVe(),...
                                        'Period', 0.5,...
                                        'ExecutionMode', 'fixedRate');
                start(app.ThoiGianDoc);
            catch
                uialert(app.UIFigure,'Lỗi khi bắt đầu', 'Lỗi');
            end
        end

        % Button pushed function: End_Button
        function End_ButtonPushed(app, event)
            % === CẬP NHẬT TRẠNG THÁI ===
            app.TrangThai = false; % Dừng đọc
            app.Status_Label.Text = 'Kết thúc đọc dữ liệu';
            app.Status_Label.FontColor = 'black'; % Màu đen
            drawnow;

            % Kiểm tra dữ liệu thời gian
            if ~isempty(app.ThoiGianDoc)
                stop(app.ThoiGianDoc);
                delete(app.ThoiGianDoc);
                app.ThoiGianDoc = [];
            end
            
            pause(1); % Tạm dừng chương trình 1s

            % === CẬP NHẬT TRẠNG THÁI ===
            app.Status_Label.Text = 'Đã kết nối';
            app.Status_Label.FontColor = [0 0.6 0.1]; % Màu xanh

            % === CẬP NHẬT NÚT LOGIC ===
            app.Start_Button.Enable = 'on';
            app.End_Button.Enable = 'off';
            app.Disconnect_Button.Enable = 'on';
            
            % Cập nhật Hiển thị
            app.NhietDo_Text.Enable = "off";
            app.DoAm_Text.Enable = 'off';
            app.NhietDo_Text.Value = '';
            app.DoAm_Text.Value = '';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.NhietDoAxe = uiaxes(app.UIFigure);
            title(app.NhietDoAxe, 'Đồ thị nhiệt độ')
            xlabel(app.NhietDoAxe, 'Thời gian (s)')
            ylabel(app.NhietDoAxe, 'Nhiệt độ (C)')
            zlabel(app.NhietDoAxe, 'Z')
            app.DoAmAxe = uiaxes(app.UIFigure);
            title(app.DoAmAxe, 'Đồ thị độ ẩm')
            xlabel(app.DoAmAxe, 'Thời gian (s)')
            ylabel(app.DoAmAxe, 'Độ ẩm (%)')
            zlabel(app.DoAmAxe, 'Z')
            app.Connect_Button = uibutton(app.UIFigure, 'push');
            app.Connect_Button.ButtonPushedFcn = createCallbackFcn(app, @Connect_ButtonPushed, true);
            app.Connect_Button.Text = 'Kết nối';
            app.TuyChon_Name = uilabel(app.UIFigure);
            app.TuyChon_Name.Text = 'Thiết lập';
            app.Start_Button = uibutton(app.UIFigure, 'push');
            app.Start_Button.ButtonPushedFcn = createCallbackFcn(app, @Start_ButtonPushed, true);
            app.Start_Button.Text = 'Bắt đầu';
            app.Disconnect_Button = uibutton(app.UIFigure, 'push');
            app.Disconnect_Button.ButtonPushedFcn = createCallbackFcn(app, @Disconnect_ButtonPushed, true);
            app.Disconnect_Button.Text = 'Ngắt kết nối';
            app.Status_Name = uilabel(app.UIFigure);
            app.Status_Name.Text = 'Trạng thái kết nối:';
            app.Status_Label = uilabel(app.UIFigure);
            app.Status_Label.Text = 'Chưa kết nối';
            app.Refresh_Button = uibutton(app.UIFigure, 'push');
            app.Refresh_Button.ButtonPushedFcn = createCallbackFcn(app, @Refresh_ButtonPushed, true);
            app.Refresh_Button.Text = 'Làm mới';
            app.DieuKhien_Name = uilabel(app.UIFigure);
            app.DieuKhien_Name.Text = 'Điều khiển';
            app.End_Button = uibutton(app.UIFigure, 'push');
            app.End_Button.ButtonPushedFcn = createCallbackFcn(app, @End_ButtonPushed, true);
            app.End_Button.Text = 'Kết thúc';
            app.CngDropDownLabel = uilabel(app.UIFigure);
            app.CngDropDownLabel.Text = 'Cổng';
            app.COM_Sec = uidropdown(app.UIFigure);
            app.COM_Sec.Items = {'Chọn cổng'};
            app.COM_Sec.Value = 'Chọn cổng';
            app.BaudRateDropDownLabel = uilabel(app.UIFigure);
            app.BaudRateDropDownLabel.Text = 'Baud Rate';
            app.BaudRate_Sec = uidropdown(app.UIFigure);
            app.BaudRate_Sec.Items = {'9600', '19200', '38400', '57600', '115200'};
            app.BaudRate_Sec.Value = '9600';
            app.Version = uilabel(app.UIFigure);
            app.Version.Text = 'Version 1.0';
            app.NhietDo_Text = uitextarea(app.UIFigure);
            app.NhitLabel = uilabel(app.UIFigure);
            app.NhitLabel.Text = 'Nhiệt độ';
            app.mTextAreaLabel = uilabel(app.UIFigure);
            app.mTextAreaLabel.Text = 'Độ ẩm';
            app.DoAm_Text = uitextarea(app.UIFigure);
            app.NameApp = uilabel(app.UIFigure);
            app.NameApp.Text = 'TRẠM THỜI TIẾT ARDUINO VÀ MATLAB';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Giao_Dien_Tram_Du_Bao

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end